classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VISUALSERVOINGMODEButton  matlab.ui.control.Button
        TOUCHTAGSButton           matlab.ui.control.Button
        EditField_9               matlab.ui.control.EditField
        Label_3                   matlab.ui.control.Label
        EditField_8               matlab.ui.control.EditField
        Label_2                   matlab.ui.control.Label
        EditField_7               matlab.ui.control.EditField
        EditField_7Label          matlab.ui.control.Label
        EditField_6               matlab.ui.control.EditField
        EditField_6Label          matlab.ui.control.Label
        EditField_5               matlab.ui.control.EditField
        EditField_5Label          matlab.ui.control.Label
        EditField_4               matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        EditField_3               matlab.ui.control.EditField
        EditField_3Label          matlab.ui.control.Label
        EditField_2               matlab.ui.control.EditField
        EditField_2Label          matlab.ui.control.Label
        TAGPOSITIONSLabel         matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        EditFieldLabel            matlab.ui.control.Label
        CALIBRATETAGSButton       matlab.ui.control.Button
        DISCONNECTButton          matlab.ui.control.Button
        CONNECTButton             matlab.ui.control.Button
        HOMEButton_2              matlab.ui.control.Button
    end


    properties (Access = private)
        robot1;

    end

    methods (Access = private)

        function UpdatePosition(app)

            %             tagPositions =
            %
            %             app.EditField.Value =
            %             app.EditField_2.Value =
            %             app.EditField_3.Value =
            %             app.EditField_4.Value =
            %             app.EditField_5.Value =
            %             app.EditField_6.Value =
            %             app.EditField_7.Value =
            %             app.EditField_8.Value =
            %             app.EditField_9.Value =
            %

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            rosinit;
            app.robot1 = DobotControl();
            disp('Robot Ready!');
        end

        % Button pushed function: HOMEButton_2
        function HOMEButton_2Pushed(app, event)
            app.robot1.HomeDobot();
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            disp('Disconnecting... Bye!');
            rosshutdown;
        end

        % Button pushed function: CALIBRATETAGSButton
        function CALIBRATETAGSButtonPushed(app, event)

            app.UpdatePosition();
        end

        % Button pushed function: TOUCHTAGSButton
        function TOUCHTAGSButtonPushed(app, event)
            for i = 1 : length()
                app.robot1.MoveToCartesianPoint
            end
        end

        % Button pushed function: VISUALSERVOINGMODEButton
        function VISUALSERVOINGMODEButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HOMEButton_2 = uibutton(app.UIFigure, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_2Pushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.CALIBRATETAGSButton = uibutton(app.UIFigure, 'push');
            app.CALIBRATETAGSButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATETAGSButtonPushed, true);
            app.CALIBRATETAGSButton.Text = {'CALIBRATE '; 'TAGS'};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '1:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TAGPOSITIONSLabel = uilabel(app.UIFigure);
            app.TAGPOSITIONSLabel.Text = 'TAG POSITIONS';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '2:';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '3:';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '4:';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '5:';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '6:';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '7:';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '8:';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '9:';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.TOUCHTAGSButton = uibutton(app.UIFigure, 'push');
            app.TOUCHTAGSButton.ButtonPushedFcn = createCallbackFcn(app, @TOUCHTAGSButtonPushed, true);
            app.TOUCHTAGSButton.Text = {'TOUCH'; 'TAGS'};
            app.VISUALSERVOINGMODEButton = uibutton(app.UIFigure, 'push');
            app.VISUALSERVOINGMODEButton.ButtonPushedFcn = createCallbackFcn(app, @VISUALSERVOINGMODEButtonPushed, true);
            app.VISUALSERVOINGMODEButton.Text = {'VISUAL'; 'SERVOING'; 'MODE'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end